classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        GraficasTab               matlab.ui.container.Tab
        Panel_2                   matlab.ui.container.Panel
        Label_3                   matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        ExportarGraficasPanel     matlab.ui.container.Panel
        GuardarButton             matlab.ui.control.Button
        Label_2                   matlab.ui.control.Label
        savename                  matlab.ui.control.EditField
        NombreLabel               matlab.ui.control.Label
        dropEXT                   matlab.ui.control.DropDown
        Panel_3                   matlab.ui.container.Panel
        GraficarButton            matlab.ui.control.Button
        SealSpinnerLabel          matlab.ui.control.Label
        SealSpinner               matlab.ui.control.Spinner
        DimensionEditFieldLabel   matlab.ui.control.Label
        DimensionEditField        matlab.ui.control.EditField
        Image                     matlab.ui.control.Image
        BasedeDatosDropDownLabel  matlab.ui.control.Label
        BasedeDatosDropDown       matlab.ui.control.DropDown
        TipodeSealPanel           matlab.ui.container.Panel
        HBLampLabel               matlab.ui.control.Label
        HBLamp                    matlab.ui.control.Lamp
        RELampLabel               matlab.ui.control.Label
        RELamp                    matlab.ui.control.Lamp
        LPLampLabel               matlab.ui.control.Label
        LPLamp                    matlab.ui.control.Lamp
        VTLampLabel               matlab.ui.control.Label
        VTLamp                    matlab.ui.control.Lamp
        TRLampLabel               matlab.ui.control.Label
        TRLamp                    matlab.ui.control.Lamp
        TCLampLabel               matlab.ui.control.Label
        TCLamp                    matlab.ui.control.Lamp
        IQLampLabel               matlab.ui.control.Label
        IQLamp                    matlab.ui.control.Lamp
        Tab2                      matlab.ui.container.Tab
    end


    properties (Access = private)
        Data
        numS
        VT
        LP
        TC
        TR
    end


    methods (Access = private)
    
        function result =cutSignal(app,cont,sig)
            sig2=[] ;
            for i=1:length(sig)
                if i==length(sig)
                    break
                end  
                    
                if sig(i)==sig(i+1)
                    cont=cont-1;
                end
                sig2=[sig2 sig(i)];
                if cont==0
                    break
                end
             end
             result=sig2;
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('VT.mat', 'VTC1');
            app.VT=VTC1;
            load('LP.mat', 'LP');
            app.LP=LP;
            load('TC.mat', 'TC');
            app.TC=TC;
            load('TR.mat', 'TR');
            app.TR=TR;
        end

        % Value changed function: BasedeDatosDropDown
        function BasedeDatosDropDownValueChanged(app, event)
            value = app.BasedeDatosDropDown.Value;
            switch value 
                case "VT"
                    app.GraficarButton.Enable='on';
                    dim=size(app.VT);
                    a=num2str(dim(1));
                    app.DimensionEditField.Value=a;
                    val=1;
                    app.SealSpinner.Value=val;
                    app.numS=1;
                case "LP"
                    app.GraficarButton.Enable='on';
                    dim=size(app.LP);
                    a=num2str(dim(1));
                    app.DimensionEditField.Value=a;
                    val=1;
                    app.SealSpinner.Value=val;
                    app.numS=1;
                case "TC"
                    app.GraficarButton.Enable='on';
                    dim=size(app.TC);
                    a=num2str(dim(1));
                    app.DimensionEditField.Value=a;
                    val=1;
                    app.SealSpinner.Value=val;
                    app.numS=1;
                case "TR"
                    app.GraficarButton.Enable='on';
                    dim=size(app.TR);
                    a=num2str(dim(1));
                    app.DimensionEditField.Value=a;
                    val=1;
                    app.SealSpinner.Value=val;
                    app.numS=1;
                case "NONE"
                    app.GraficarButton.Enable='on';
                    a='--';
                    app.DimensionEditField.Value=a;
                    val=1;
                    app.SealSpinner.Value=val;
                    app.GraficarButton.Enable='off';
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            value = app.BasedeDatosDropDown.Value;
            switch value 
                case "VT"
                    
                    num=app.numS;
                    sig=app.VT(num,:);
                    sig2=cutSignal(app,5,sig);  %quitar inicio y final
                    
                    sig2=sig2-mean(sig2); %limpiar la señal
                    
                    Fs=100;
                    tms = (0:numel(sig2)-1)/Fs;
                    %grafica del tiempo
                    plot(app.UIAxes,tms,sig2,'-r')
                    
                    %grafica del psd con ventana de welch
%                     [pxx,f] = pwelch(sig2,500,450,500,10);
%                     plot(app.UIAxes2,f,10*log10(pxx))
                    welchMuestra=pwelch(sig2);
                    twelch=linspace(0,50,length(welchMuestra));
                    plot(app.UIAxes2,twelch,10*log10(welchMuestra),'Color','red')
                    
                    %grafica de la energía 
                    [cfs,frq] = cwt(sig2,Fs);
                    surf(app.UIAxes4,tms,frq,abs(cfs));
                    view(app.UIAxes4,[-270 90])
                    set(app.UIAxes4,'XDir','reverse')
                    shading(app.UIAxes4 ,'flat')
                    axis(app.UIAxes4 , 'tight')
                   % app.UIAxes4.YScale='log';
                    
                case "LP"
                    %tratamiento de la señal
                    num=app.numS;
                    sig=app.LP(num,:);
                    sig=cutSignal(app,5,sig);
                    
                    sig=sig-mean(sig); %limpiar la señal
                    
                    Fs=100;
                    tms = (0:numel(sig)-1)/Fs;
                    %grafica del tiempo
                    plot(app.UIAxes,tms,sig,'-b')
                    
                    %grafica de psd
                    
                    welchMuestra=pwelch(sig);
                    twelch=linspace(0,50,length(welchMuestra));
                    plot(app.UIAxes2,twelch,10*log10(welchMuestra),'Color','red')
                    
                    
                    %grafica de la energía
                    [cfs,frq] = cwt(sig,Fs);
                    surf(app.UIAxes4,tms,frq,abs(cfs));
                    view(app.UIAxes4,[-270 90])
                    set(app.UIAxes4,'XDir','reverse')
                    shading(app.UIAxes4 ,'flat')
                    axis(app.UIAxes4 , 'tight')
                    %app.UIAxes4.YScale='log';
                   
                    
                case "TC"
                    %extraccion y tratamiento de la señal
                    num=app.numS;
                    sig=app.TC(num,:);
                    sig=cutSignal(app,5,sig);
                    
                    sig=sig-mean(sig); %limpiar la señal
                    
                    Fs=100;
                    tms = (0:numel(sig)-1)/Fs;
                    %grafica del tiempo
                    plot(app.UIAxes,tms,sig,'-m')
                    
                    %grafica de la pSD
                    welchMuestra=pwelch(sig);
                    twelch=linspace(0,50,length(welchMuestra));
                    plot(app.UIAxes2,twelch,10*log10(welchMuestra),'Color','red')
                    
                    %grafica de la energía
                    cla(app.UIAxes4)
                    [cfs,frq] = cwt(sig,Fs);
                    surf(app.UIAxes4,tms,frq,abs(cfs));
                    view(app.UIAxes4,[-270 90])
                    set(app.UIAxes4,'XDir','reverse')
                    shading(app.UIAxes4 ,'flat')
                    axis(app.UIAxes4 , 'tight')
                   % app.UIAxes4.YScale='log';
                    
                case "TR"
                    %extraccion y tratamiento de la señal
                    num=app.numS;
                    sig=app.TR(num,:);
                    sig=cutSignal(app,5,sig);
                    
                    sig=sig-mean(sig); %limpiar la señal
                    
                    Fs=100;
                    tms = (0:numel(sig)-1)/Fs;
                    
                    %grafica del tiempo
                    plot(app.UIAxes,tms,sig,'-k')
                    
                    %grafica de la psd
                    welchMuestra=pwelch(sig);
                    twelch=linspace(0,50,length(welchMuestra));
                    plot(app.UIAxes2,twelch,10*log10(welchMuestra),'Color','red')
                    
                    %grafica de la energía
                    cla(app.UIAxes4)
                    [cfs,frq] = cwt(sig,Fs);
                    surf(app.UIAxes4,tms,frq,abs(cfs));
                    view(app.UIAxes4,[-270 90])
                    set(app.UIAxes4,'XDir','reverse')
                    shading(app.UIAxes4 ,'flat')
                    axis(app.UIAxes4 , 'tight')
%                     axis(app.UIAxes4 , 'tight')


                    %app.UIAxes4.YScale='log';
                    
                 
            end
        end

        % Value changing function: SealSpinner
        function SealSpinnerValueChanging(app, event)
            app.numS = event.Value;
            
        end

        % Value changed function: SealSpinner
        function SealSpinnerValueChanged(app, event)
            app.numS = app.SealSpinner.Value;
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            value = app.dropEXT.Value;
           % exportapp(app.UIFigure, '.\Images\Image.png')
            %saveas(app.UIAxes, 'prueba', '.png');
            if app.savename =="" 
                
            elseif value ~= "EXT"
             
                % Copy all UIAxes children, take over axes limits and aspect ratio.            
                name =  ["-time","-PSD","-Power"] ;
                
                uiAx=[app.UIAxes,app.UIAxes2,app.UIAxes4];
                for i=1:length(uiAx)
                  
                    fig = figure;
                    fig.Visible = 'off';
                    
                    figAxes = axes(fig);
                    allChildren = uiAx(i).XAxis.Parent.Children;
%                     
                    copyobj(allChildren,figAxes,"legacy")
                    figAxes.CameraPosition=uiAx(i).CameraPosition;
                    figAxes.CameraPositionMode=uiAx(i).CameraPositionMode;
                    figAxes.CameraTarget=uiAx(i).CameraTarget;
                    figAxes.CameraTargetMode=uiAx(i).CameraTargetMode;
                    figAxes.CameraUpVector=uiAx(i).CameraUpVector;
                    figAxes.CameraUpVectorMode=uiAx(i).CameraUpVectorMode;
                    figAxes.CameraViewAngle=uiAx(i).CameraViewAngle;
                    figAxes.CameraViewAngleMode=uiAx(i).CameraViewAngleMode;
                    
                    figAxes.View = uiAx(i).View;
                    figAxes.Projection=uiAx(i).Projection;
                    figAxes.LabelFontSizeMultiplier=uiAx(i).LabelFontSizeMultiplier;
                    figAxes.AmbientLightColor=uiAx(i).AmbientLightColor;                     
                    figAxes.DataAspectRatio = uiAx(i).DataAspectRatio;
                    figAxes.DataAspectRatioMode=uiAx(i).DataAspectRatioMode;
                    figAxes.PlotBoxAspectRatio=uiAx(i).PlotBoxAspectRatio;
                    figAxes.PlotBoxAspectRatioMode=uiAx(i).PlotBoxAspectRatioMode;
                    
                    figAxes.TickLabelInterpreter=uiAx(i).TickLabelInterpreter;
                    figAxes.XLim = uiAx(i).XLim;
                    figAxes.YLim = uiAx(i).YLim;
                    figAxes.ZLim = uiAx(i).ZLim;
                    figAxes.XDir=uiAx(i).XDir;
                    figAxes.YDir=uiAx(i).YDir;
                    figAxes.ZDir=uiAx(i).ZDir;
                    
                    figAxes.YLabel.String = uiAx(i).YLabel.String;
                    figAxes.YLabel.FontSize = uiAx(i).YLabel.FontSize;
                    figAxes.XLabel.String = uiAx(i).XLabel.String;
                    figAxes.XLabel.FontSize = uiAx(i).XLabel.FontSize;
                    figAxes.Title.String = uiAx(i).Title.String;
                    figAxes.Title.FontSize = uiAx(i).Title.FontSize;
                    
                    auxname=app.savename.Value+name(i);
                    %disp(auxname)
                    %hgsave(fig, auxname);
                    saveas(fig,auxname,value)
                    delete(fig);
                    auxname="";
                 
                end
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraficasTab = uitab(app.TabGroup);
            app.GraficasTab.Title = 'Graficas';
            app.Panel_2 = uipanel(app.GraficasTab);
            app.Label_3 = uilabel(app.Panel_2);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Señal Sismica')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes3 = uiaxes(app.Panel_2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'x')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.Panel_2);
            title(app.UIAxes4, 'Espectro en Energia')
            xlabel(app.UIAxes4, 'Tiempo')
            ylabel(app.UIAxes4, 'Frecuencia')
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'PSD-Welch')
            xlabel(app.UIAxes2, 'Frecuencia')
            ylabel(app.UIAxes2, 'DB/Hz')
            app.ExportarGraficasPanel = uipanel(app.GraficasTab);
            app.ExportarGraficasPanel.Title = 'Exportar Graficas';
            app.GuardarButton = uibutton(app.ExportarGraficasPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Label_2 = uilabel(app.ExportarGraficasPanel);
            app.Label_2.Text = '';
            app.savename = uieditfield(app.ExportarGraficasPanel, 'text');
            app.NombreLabel = uilabel(app.ExportarGraficasPanel);
            app.NombreLabel.Text = 'Nombre';
            app.dropEXT = uidropdown(app.ExportarGraficasPanel);
            app.dropEXT.Items = {'EXT', 'jpg', 'png', 'eps'};
            app.dropEXT.Value = 'EXT';
            app.Panel_3 = uipanel(app.GraficasTab);
            app.Panel_3.Title = 'Panel';
            app.GraficarButton = uibutton(app.Panel_3, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.SealSpinnerLabel = uilabel(app.Panel_3);
            app.SealSpinnerLabel.Text = 'Señal';
            app.SealSpinner = uispinner(app.Panel_3);
            app.SealSpinner.ValueChangingFcn = createCallbackFcn(app, @SealSpinnerValueChanging, true);
            app.SealSpinner.Limits = [1 1310];
            app.SealSpinner.ValueChangedFcn = createCallbackFcn(app, @SealSpinnerValueChanged, true);
            app.SealSpinner.Value = 1;
            app.DimensionEditFieldLabel = uilabel(app.Panel_3);
            app.DimensionEditFieldLabel.Text = 'Dimension';
            app.DimensionEditField = uieditfield(app.Panel_3, 'text');
            app.DimensionEditField.Editable = 'off';
            app.Image = uiimage(app.GraficasTab);
            app.Image.ImageSource = 'Logo_ESPE.png';
            app.BasedeDatosDropDownLabel = uilabel(app.GraficasTab);
            app.BasedeDatosDropDownLabel.Text = 'Base de Datos';
            app.BasedeDatosDropDown = uidropdown(app.GraficasTab);
            app.BasedeDatosDropDown.Items = {'NONE', 'VT', 'LP', 'TC', 'TR'};
            app.BasedeDatosDropDown.ValueChangedFcn = createCallbackFcn(app, @BasedeDatosDropDownValueChanged, true);
            app.BasedeDatosDropDown.Value = 'NONE';
            app.TipodeSealPanel = uipanel(app.GraficasTab);
            app.TipodeSealPanel.Title = 'Tipo de Señal';
            app.HBLampLabel = uilabel(app.TipodeSealPanel);
            app.HBLampLabel.Text = 'HB';
            app.HBLamp = uilamp(app.TipodeSealPanel);
            app.RELampLabel = uilabel(app.TipodeSealPanel);
            app.RELampLabel.Text = 'RE';
            app.RELamp = uilamp(app.TipodeSealPanel);
            app.LPLampLabel = uilabel(app.TipodeSealPanel);
            app.LPLampLabel.Text = 'LP';
            app.LPLamp = uilamp(app.TipodeSealPanel);
            app.VTLampLabel = uilabel(app.TipodeSealPanel);
            app.VTLampLabel.Text = 'VT';
            app.VTLamp = uilamp(app.TipodeSealPanel);
            app.TRLampLabel = uilabel(app.TipodeSealPanel);
            app.TRLampLabel.Text = 'TR';
            app.TRLamp = uilamp(app.TipodeSealPanel);
            app.TCLampLabel = uilabel(app.TipodeSealPanel);
            app.TCLampLabel.Text = 'TC';
            app.TCLamp = uilamp(app.TipodeSealPanel);
            app.IQLampLabel = uilabel(app.TipodeSealPanel);
            app.IQLampLabel.Text = 'IQ';
            app.IQLamp = uilamp(app.TipodeSealPanel);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end